classdef MAMA < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        TabGroup                     matlab.ui.container.TabGroup
        SurveyTab                    matlab.ui.container.Tab
        GenderListBoxLabel           matlab.ui.control.Label
        GenderListBox                matlab.ui.control.ListBox
        AgeEditFieldLabel            matlab.ui.control.Label
        AgeEditField                 matlab.ui.control.NumericEditField
        UFEmailEditFieldLabel        matlab.ui.control.Label
        UFEmailEditField             matlab.ui.control.EditField
        GamingExperienceButtonGroup  matlab.ui.container.ButtonGroup
        hrsweekButton                matlab.ui.control.RadioButton
        hrsweekButton_2              matlab.ui.control.RadioButton
        hrsweekButton_3              matlab.ui.control.RadioButton
        hrsweekButton_4              matlab.ui.control.RadioButton
        hrsweekButton_5              matlab.ui.control.RadioButton
        NoneButton                   matlab.ui.control.RadioButton
        HaveyouParticipatedinaSoundPADexperimentbeforeButtonGroup  matlab.ui.container.ButtonGroup
        YesButton                    matlab.ui.control.RadioButton
        NoButton                     matlab.ui.control.RadioButton
        DominantHandButtonGroup      matlab.ui.container.ButtonGroup
        RightButton                  matlab.ui.control.RadioButton
        LeftButton                   matlab.ui.control.RadioButton
        PleasechooseyourpreferredgenderLabel  matlab.ui.control.Label
        EnterYourageLabel            matlab.ui.control.Label
        EthnicityListBoxLabel        matlab.ui.control.Label
        EthnicityListBox             matlab.ui.control.ListBox
        EnteryourUFEmailLabel        matlab.ui.control.Label
        Label                        matlab.ui.control.Label
        DoyouhaveanymobilePCorconsolegamingexperienceLabel  matlab.ui.control.Label
        ExtraCreditButton            matlab.ui.container.ButtonGroup
        YesButton_2                  matlab.ui.control.RadioButton
        NoButton_2                   matlab.ui.control.RadioButton
        AreyoucompletingthisforextracreditLabel  matlab.ui.control.Label
        SelectCourseDropDownLabel    matlab.ui.control.Label
        SelectCourseDropDown         matlab.ui.control.DropDown
        ExperimentTab                matlab.ui.container.Tab
        PlaySoundButton              matlab.ui.control.Button
        SubmitButton                 matlab.ui.control.Button
        OnsetAngleSliderLabel        matlab.ui.control.Label
        OnsetAngleSlider             matlab.ui.control.Slider
        OffsetAngleSliderLabel       matlab.ui.control.Label
        OffsetAngleSlider            matlab.ui.control.Slider
        AzimuthPlanePanel            matlab.ui.container.Panel
        Image                        matlab.ui.control.Image
    end

    
    properties (Access = private)
        Property % Description
        PolarAxis
        userOnsetAngle
        userOffsetAngle
        measuredOnsetAngle
        measuredOffsetAngle
        onsetAngleDifference
        offsetAngleDifference
        meanOnsetDifference
        meanOffsetDifference
        trial
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.PolarAxis = polaraxes(app.AzimuthPlanePanel);
            app.OnsetAngleSlider.Enable = "off";
            app.OffsetAngleSlider.Enable = "off";
            app.SubmitButton.Enable = 'off';
            
            app.userOnsetAngle = [];
            app.userOffsetAngle = [];
            app.measuredOffsetAngle = [];
            app.measuredOnsetAngle = [];
            app.onsetAngleDifference = [];
            app.onsetAngleDifference = [];
            app.trial = 1;
            
            
        end

        % Button pushed function: PlaySoundButton
        function PlaySoundButtonPushed(app, event)
            

            % Play moving stimulus
            
            app.PlaySoundButton.Enable = 'off';
            pause(3);
            app.OnsetAngleSlider.Enable = "on";
            app.OffsetAngleSlider.Enable = "on";
            app.SubmitButton.Enable = "on";
            app.PlaySoundButton.Enable = 'on';
        end

        % Button pushed function: SubmitButton
        function SubmitButtonPushed(app, event)
            app.userOffsetAngle(end + 1) = 90 -app.OffsetAngleSlider.Value; % adjust the values so that ^ is 0 degrees, to the right is positive, and to the left is negative
            app.userOnsetAngle(end + 1) = 90 - app.OnsetAngleSlider.Value;
            
            % find a way to measure the actual onset/offset angle from the stimulus
            % for testing purposes they will always be equal
            app.measuredOnsetAngle = app.userOnsetAngle;
            app.measuredOffsetAngle = app.userOffsetAngle;
            
            % difference should be 0 during testing
            
            app.onsetAngleDifference(end + 1) = app.userOnsetAngle(app.trial) - app.measuredOnsetAngle(app.trial);
            app.offsetAngleDifference(end + 1) = app.userOffsetAngle(app.trial) - app.measuredOffsetAngle(app.trial);
            app.trial = app.trial + 1;
            
            app.OnsetAngleSlider.Enable = "off";
            app.OffsetAngleSlider.Enable = "off";
            app.SubmitButton.Enable = 'off';
            cla(app.PolarAxis);
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            GatorLink = "Temp";
            UF_Email = string(app.UFEmailEditField.Value);
            Age = string(app.AgeEditField.Value);
            Ethnicity = string(app.EthnicityListBox.Value);

            Gaming_Experience = "Temp";
            if app.hrsweekButton.Value == 1
                Gaming_Experience = string(app.hrsweekButton.Text);
            elseif app.hrsweekButton_2.Value == 1
                Gaming_Experience = string(app.hrsweekButton_2.Text);
            elseif app.hrsweekButton_3.Value == 1
                Gaming_Experience = string(app.hrsweekButton_3.Text);
            elseif app.hrsweekButton_4.Value == 1
                Gaming_Experience = string(app.hrsweekButton_4.Text);
            elseif app.hrsweekButton_5.Value == 1
                Gaming_Experience = string(app.hrsweekButton_5.Text);
            elseif app.NoneButton == 1
                Gaming_Experience = string(app.NoneButton.Text);
            end

            Dominant_Hand = "Temp";
            if app.RightButton.Value == 1
                Dominant_Hand = "Right";
            elseif app.LeftButton.Value == 1
                Dominant_Hand = "Left";
            end
            
            HaveYouParticipatedInASoundPADExperimentBefore = "No";
            if app.NoButton.Value == 1
                HaveYouParticipatedInASoundPADExperimentBefore = "No";
            elseif app.YesButton.Value == 1
                HaveYouParticipatedInASoundPADExperimentBefore = "Yes";
            end

            extraCredit = "Temp";
            if app.NoButton_2.Value == 1
                 extraCredit = "No";
            elseif app.YesButton_2.Value == 1
                 extraCredit = "Yes";
            end
            
            app.meanOnsetDifference = mean(app.onsetAngleDifference);
            app.meanOffsetDifference = mean(app.offsetAngleDifference);


            participant = table( ...
                GatorLink, ...
                UF_Email, ...
                Age, ...
                Ethnicity, ...
                string(app.SelectCourseDropDown.Value), ...
                Gaming_Experience, ...
                extraCredit, ...
                Dominant_Hand, ...
                HaveYouParticipatedInASoundPADExperimentBefore, ...
                'VariableNames', ...
                {'GatorLink', ...
                'UF Email',...
                'Age', ...
                'Ethinicity', ...
                'Course', ...
                'Gaming Experience', ...
                'Extra Credit', ...
                'Dominant Hand', ...
                'Have you participated in a SoundPAD experiment before?'});
            
            data = table(app.userOnsetAngle(:), ...
                app.userOffsetAngle(:), ...
                app.measuredOnsetAngle(:), ...
                app.measuredOffsetAngle(:), ...
                app.onsetAngleDifference(:), ...
                app.offsetAngleDifference(:), ...
                'VariableNames', ...
                {'User Onset Angles', ...
                'User Offset Angles', ...
                'Audio Onset Angles', ...
                'Audio Offset Angles', ...
                'Onset Angle Difference', ...
                'Offset Angle Difference'});
            
            meanDiff = table(app.meanOnsetDifference, app.meanOffsetDifference, ...
                'VariableNames', {'Mean Onset Difference', 'Mean Offset Difference'});

            dt = datetime("now", 'TimeZone', "local", 'Format', "dd-MMM-uuuu HH:mm:ss");
            filename = strcat(string(dt), ".xlsx");
            writetable(participant, filename, 'Sheet', 1, 'Range', 'A1');
            writetable(data, filename, 'Range', 'J1:O200');
            writetable(meanDiff, filename, 'Range', 'P1');
            delete(app)
        end

        % Value changed function: OnsetAngleSlider
        function OnsetAngleSliderValueChanged(app, event)
        hold(app.PolarAxis, 'off')
        % Get the angle selected by the slider
        angle = app.OnsetAngleSlider.Value;
        % Plot the line from the center to the selected angle
        polarplot(app.PolarAxis, [angle; angle] * pi/180, [0;1], 'r'); % Plot line in red
        hold(app.PolarAxis, 'on')
        end

        % Value changed function: OffsetAngleSlider
        function OffsetAngleSliderValueChanged(app, event)
            hold(app.PolarAxis, 'off');
            angle2 = app.OffsetAngleSlider.Value;
            polarplot(app.PolarAxis, [angle2; angle2] * pi/180, [0;1], 'b');
            hold(app.PolarAxis, 'on')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.SurveyTab = uitab(app.TabGroup);
            app.SurveyTab.Title = 'Survey';
            app.GenderListBoxLabel = uilabel(app.SurveyTab);
            app.GenderListBoxLabel.Text = 'Gender';
            app.GenderListBox = uilistbox(app.SurveyTab);
            app.GenderListBox.Items = {'Male', 'Female', 'Non-Binary', 'Other', 'Prefer not to say'};
            app.GenderListBox.Value = 'Male';
            app.AgeEditFieldLabel = uilabel(app.SurveyTab);
            app.AgeEditFieldLabel.Text = 'Age';
            app.AgeEditField = uieditfield(app.SurveyTab, 'numeric');
            app.UFEmailEditFieldLabel = uilabel(app.SurveyTab);
            app.UFEmailEditFieldLabel.Text = 'UF Email';
            app.UFEmailEditField = uieditfield(app.SurveyTab, 'text');
            app.GamingExperienceButtonGroup = uibuttongroup(app.SurveyTab);
            app.GamingExperienceButtonGroup.Title = '                                                       ';
            app.hrsweekButton = uiradiobutton(app.GamingExperienceButtonGroup);
            app.hrsweekButton.Text = '0-5 hrs/week';
            app.hrsweekButton.Value = true;
            app.hrsweekButton_2 = uiradiobutton(app.GamingExperienceButtonGroup);
            app.hrsweekButton_2.Text = '6-10 hrs/week';
            app.hrsweekButton_3 = uiradiobutton(app.GamingExperienceButtonGroup);
            app.hrsweekButton_3.Text = '11-15 hrs/week';
            app.hrsweekButton_4 = uiradiobutton(app.GamingExperienceButtonGroup);
            app.hrsweekButton_4.Text = '16-20 hrs/week';
            app.hrsweekButton_5 = uiradiobutton(app.GamingExperienceButtonGroup);
            app.hrsweekButton_5.Text = '21+ hrs/week';
            app.NoneButton = uiradiobutton(app.GamingExperienceButtonGroup);
            app.NoneButton.Text = 'None';
            app.HaveyouParticipatedinaSoundPADexperimentbeforeButtonGroup = uibuttongroup(app.SurveyTab);
            app.HaveyouParticipatedinaSoundPADexperimentbeforeButtonGroup.Title = 'Have you Participated in a SoundPAD experiment before?';
            app.YesButton = uiradiobutton(app.HaveyouParticipatedinaSoundPADexperimentbeforeButtonGroup);
            app.YesButton.Text = 'Yes';
            app.YesButton.Value = true;
            app.NoButton = uiradiobutton(app.HaveyouParticipatedinaSoundPADexperimentbeforeButtonGroup);
            app.NoButton.Text = 'No';
            app.DominantHandButtonGroup = uibuttongroup(app.SurveyTab);
            app.DominantHandButtonGroup.Title = 'Dominant Hand';
            app.RightButton = uiradiobutton(app.DominantHandButtonGroup);
            app.RightButton.Text = 'Right';
            app.RightButton.Value = true;
            app.LeftButton = uiradiobutton(app.DominantHandButtonGroup);
            app.LeftButton.Text = 'Left';
            app.PleasechooseyourpreferredgenderLabel = uilabel(app.SurveyTab);
            app.PleasechooseyourpreferredgenderLabel.Text = 'Please choose your preferred gender:';
            app.EnterYourageLabel = uilabel(app.SurveyTab);
            app.EnterYourageLabel.Text = 'Enter Your age:';
            app.EthnicityListBoxLabel = uilabel(app.SurveyTab);
            app.EthnicityListBoxLabel.Text = 'Ethnicity';
            app.EthnicityListBox = uilistbox(app.SurveyTab);
            app.EthnicityListBox.Items = {'White', 'Black or African American', 'Latino or Hispanic', 'Asian', 'Native Hawaiian or Other Pacific Islander', 'American Indian or Alaska Native', 'Other', 'Prefer not to say'};
            app.EthnicityListBox.Multiselect = 'on';
            app.EthnicityListBox.Value = {'White'};
            app.EnteryourUFEmailLabel = uilabel(app.SurveyTab);
            app.EnteryourUFEmailLabel.Text = 'Enter your UF Email: ';
            app.Label = uilabel(app.SurveyTab);
            app.Label.Text = 'This portion of the study is meant to collect demographic information about you. Please answer the questions and make selections to the best of your abilities. If you have any questions about this portion, do not hesitate to ask the person currently running the study. When you are finished with this part, you may select the tab in the top left corner that reads "Experiment" and continue with the process.';
            app.DoyouhaveanymobilePCorconsolegamingexperienceLabel = uilabel(app.SurveyTab);
            app.DoyouhaveanymobilePCorconsolegamingexperienceLabel.Text = 'Do you have any mobile, PC, or console gaming experience?';
            app.ExtraCreditButton = uibuttongroup(app.SurveyTab);
            app.ExtraCreditButton.Title = '                                                        ';
            app.YesButton_2 = uiradiobutton(app.ExtraCreditButton);
            app.YesButton_2.Text = 'Yes';
            app.YesButton_2.Value = true;
            app.NoButton_2 = uiradiobutton(app.ExtraCreditButton);
            app.NoButton_2.Text = 'No';
            app.AreyoucompletingthisforextracreditLabel = uilabel(app.SurveyTab);
            app.AreyoucompletingthisforextracreditLabel.Text = 'Are you completing this for extra credit?';
            app.SelectCourseDropDownLabel = uilabel(app.SurveyTab);
            app.SelectCourseDropDownLabel.Text = 'Select Course';
            app.SelectCourseDropDown = uidropdown(app.SurveyTab);
            app.SelectCourseDropDown.Items = {'COP3502C', 'COP3503C', 'COP3530', 'Other'};
            app.SelectCourseDropDown.Value = 'COP3502C';
            app.ExperimentTab = uitab(app.TabGroup);
            app.ExperimentTab.Title = 'Experiment';
            app.PlaySoundButton = uibutton(app.ExperimentTab, 'push');
            app.PlaySoundButton.ButtonPushedFcn = createCallbackFcn(app, @PlaySoundButtonPushed, true);
            app.PlaySoundButton.Text = 'Play Sound';
            app.SubmitButton = uibutton(app.ExperimentTab, 'push');
            app.SubmitButton.ButtonPushedFcn = createCallbackFcn(app, @SubmitButtonPushed, true);
            app.SubmitButton.Text = 'Submit';
            app.OnsetAngleSliderLabel = uilabel(app.ExperimentTab);
            app.OnsetAngleSliderLabel.Text = 'Onset Angle';
            app.OnsetAngleSlider = uislider(app.ExperimentTab);
            app.OnsetAngleSlider.Limits = [0 180];
            app.OnsetAngleSlider.MajorTicks = [0 30 60 90 120 150 180];
            app.OnsetAngleSlider.ValueChangedFcn = createCallbackFcn(app, @OnsetAngleSliderValueChanged, true);
            app.OffsetAngleSliderLabel = uilabel(app.ExperimentTab);
            app.OffsetAngleSliderLabel.Text = 'Offset Angle';
            app.OffsetAngleSlider = uislider(app.ExperimentTab);
            app.OffsetAngleSlider.Limits = [0 180];
            app.OffsetAngleSlider.MajorTicks = [0 30 60 90 120 150 180];
            app.OffsetAngleSlider.ValueChangedFcn = createCallbackFcn(app, @OffsetAngleSliderValueChanged, true);
            app.OffsetAngleSlider.MinorTicks = [0 3 6 9 12 15 18 21 24 27 30 33 36 39 42 45 48 51 54 57 60 63 66 69 72 75 78 81 84 87 90 93 96 99 102 105 108 111 114 117 120 123 126 129 132 135 138 141 144 147 150 153 156 159 162 165 168 171 174 177 180];
            app.AzimuthPlanePanel = uipanel(app.ExperimentTab);
            app.AzimuthPlanePanel.Title = 'Azimuth Plane';
            app.Image = uiimage(app.AzimuthPlanePanel);
            app.Image.ImageSource = 'user-icon-vector-png-6.png';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MAMA

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end